classdef AI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        EmergancyFunctionsPanel  matlab.ui.container.Panel
        BatteryLevelGauge        matlab.ui.control.LinearGauge
        BatteryLevelGaugeLabel   matlab.ui.control.Label
        EmergancyLandButton      matlab.ui.control.Button
        ReturnHomeButton         matlab.ui.control.Button
        DroneRespondingPanel     matlab.ui.container.Panel
        CheckpointBLamp          matlab.ui.control.Lamp
        CheckpointBLampLabel     matlab.ui.control.Label
        CheckpointALamp          matlab.ui.control.Lamp
        CheckpointALampLabel     matlab.ui.control.Label
        MonitorAreaPanel         matlab.ui.container.Panel
        TimeDropDown             matlab.ui.control.DropDown
        TimeDropDownLabel        matlab.ui.control.Label
        CheckpointBButton        matlab.ui.control.Button
        CheckpointAButton        matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        AlertsEditField          matlab.ui.control.EditField
        AlertsEditFieldLabel     matlab.ui.control.Label
        LiveFeedLabel            matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)

        drone;
        cam;
        detector;

    end
    
    
    methods (Access = public)
        
        function alarm(app)
            
            %make "t" empty
            t = [];
            %defines serial port and baud rate
            s = serialport("COM5",115200);
            
            while (isempty(t))
                
                %red serial line
                t = readline(s);
            
            end

            if contains(t,"A")
                
                if a == 0
                    
                    respondA(app);    
                
                elseif a == 1
                   
                    %Pop-up
                    uialert(figurealert,"Alarm at Checkpoint A. No drones available","ALERT!","Modal",true)
                    
                end


            elseif contains(t,"B")

                if a == 0
                    
                respondB(app);
                
                elseif a == 1
                   
                    %Pop-up
                    uialert(figurealert,"Alarm at Checkpoint B. No drones available","ALERT!","Modal",true)
                    
                end
                                
            else

                %Pop-up
                uialert(figurealert,"Alarm at Checkpoint B. No drones available","ALERT!","Modal",false)

            end
              
              
        end

        function respondA(app)
            %% Responce set-up

            %get battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;

            %prevents operational input from user
            app.CheckpointAButton.Enable = 'off';
            app.CheckpointBButton.Enable = 'off';
            
            %Change lamp to green
            app.CheckpointALamp.Color = [0 1 0];

            %Display in Alerts box
            app.AlertsEditField.Value = 'Drone Responding To Alarm At Checkpoint A';
            
            %% Drone Movements

            %takeoff and move to desired location
            takeoff(app.drone);
            moveleft(app.drone,'Distance',2,'Speed',1);
            
            %start timer
            tim = tic;
            %while loop to look for text for 10 seconds or until text found
            while isempty(txt) || toc(tim)<10
            
                %takepicture
                I = snapshot(app.cam);
                %Identifies text using OCR
                txt = ocr(I);
            
            end
            
            %If function to compare located text
            %if true
            if contains(txt.Text,"CHECKPOINT A") || contains(txt.Text,"CHECKPOINTA")
                                                          
                %display in alerts line
                app.AlertsEditField.Value = 'Drone at Checkpoint A';
                
                montim = tic;
                while (toc(montim) <= m)
                    
                    %takes image from camera
                    I = snapshot(app.cam);
                    %logs detected objects, their positions & confidence
                    %scores
                    [bboxes, scores, labels] = detect(detector, I)
                    %determines if vehicle or car is present
                    person = ismember("person",labels)
                    car = ismember("car",labels)
                    
                    %if present detected function
                    if person == 1 || car ==1
                        
                        %display in alerts line
                        app.AlertsEditField.Value = ('Presense at "CHECKPOINT B" detected');
                        break
                        
                    end
                    
                end
                
                %if present detected function
                if person == 1 || car ==1
                        
                    %insert annotation to show position of detection
                    I = insertObjectAnnotation(I,'rectangle',bboxes,labels);
                    %display annotated image
                    figure, imshow(I);
         
                    uialert(figurealert,"PRESENSE DETECTED! Press okay to return drone home","ALERT!","Modal",true);
                    uiwait(figurealert);
                        
                else
                   
                    %display in alerts line
                    app.AlertsEditField.Value = 'No presence detected. Drone returning home.';
                    %move drone home
                    moveleft(app.drone,'Distance',2,'Speed',1);
                    land(app.drone);
                end
                
                if app.drone.State ~= "landed"
                %move drone home
                moveleft(app.drone,'Distance',2,'Speed',1);
                land(app.drone);
                %display in alerts line
                app.AlertsEditField.Value = 'Drone Home';
                
                end
                
                %change indicator to red
                app.CheckpointALamp.Color = [1 0 0];
                %re-enable input from user
                app.CheckpointAButton.Enable = 'on';
                app.CheckpointBButton.Enable = 'on';
            
            else
            
                %display in command line
                app.AlertsEditField.Value = 'Drone lost';
                %land drone
                land(app.drone);
            
            end

        end

        function respondB(app)
            %% Responce set-up
            %get battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;

            %prevents operational input from user
            app.CheckpointAButton.Enable = 'off';
            app.CheckpointBButton.Enable = 'off';
            
            %Change lamp to green
            app.CheckpointALamp.Color = [0 1 0];

            %Display in Alerts box
            app.AlertsEditField.Value = 'Drone Responding To Alarm At Checkpoint B';
            
            %% Drone Movements

            %takeoff and move to desired location
            takeoff(app.drone);
            moveright(app.drone,'Distance',2,'Speed',1);
            
            %start timer
            tim = tic;
            %while loop to look for text for 10 seconds or until text found
            while isempty(txt) || toc(tim)<10
            
                %takepicture
                I = snapshot(app.cam);
                %Identifies text using OCR
                txt = ocr(I);
            
            end
            
            %If function to compare located text
            %if true
            if contains(txt.Text,"CHECKPOINT B") || contains(txt.Text,"CHECKPOINTB")
            
                %display in alerts line
                app.AlertsEditField.Value = 'Drone at Checkpoint B';
                
                montim = tic;
                while (toc(montim) <= m)
                    
                    %takes image from camera
                    I = snapshot(app.cam);
                    %logs detected objects, their positions & confidence
                    %scores
                    [bboxes, scores, labels] = detect(detector, I)
                    %determines if vehicle or car is present
                    person = ismember("person",labels);
                    car = ismember("car",labels);
                    
                    %if present detected function
                    if person == 1 || car ==1
                        
                        %display in alerts line
                        app.AlertsEditField.Value = ('Presense at "CHECKPOINT B" detected');
                        break
                        
                    end
                    
                end
                
                %if present detected function
                if person == 1 || car ==1
                        
                    %insert annotation to show position of detection
                    I = insertObjectAnnotation(I,'rectangle',bboxes,labels);
                    %display annotated image
                    dI = figure('WindowState','minimised');
                    dI, imshow(I);
         
                    uialert(figurealert,"PRESENSE DETECTED! Press okay to return drone home","ALERT!","Modal",true);
                    uiwait(figurealert);
                        
                else
                   
                    %display in alerts line
                    app.AlertsEditField.Value = 'No presence detected. Drone returning home.';
                    %move drone home
                    moveleft(app.drone,'Distance',2,'Speed',1);
                    land(app.drone);
                end
                
                if app.drone.State ~= "landed"
                %move drone home
                moveleft(app.drone,'Distance',2,'Speed',1);
                land(app.drone);
                %display in alerts line
                app.AlertsEditField.Value = 'Drone Home';
                
                end
                
                %change indicator to red
                app.CheckpointALamp.Color = [1 0 0];
                %re-enable input from user
                app.CheckpointAButton.Enable = 'on';
                app.CheckpointBButton.Enable = 'on';
            
            else
            
                %display in command line
                app.AlertsEditField.Value = 'Drone lost';
                %land drone
                land(app.drone);
            
            end


        end

    end
        
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %connect to drone and camera
            app.drone = ryze();
            app.cam = camera(app.drone);
            
            %set-up camera view frame
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes,'tight');
            %display feed into above frame
            preview(app.cam,im);
            %release axes view to show feed
            app.UIAxes.Visible = 'on';
            %get initial battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;
            
            pretrained = load('tinyYOLOV2-coco.mat');
            app.detector = pretrained.yolov2Detector;

            t = timer;
            t.Period = 5;
            t.TasksToExecute = inf;
            t.ExecutionMode = 'fixedRate';
            t.TimerFcn = @(~,~)alarm(app);
            
            start(t);


        end

        % Button pushed function: CheckpointAButton
        function CheckpointAButtonPushed(app, event)
            %% Monitor Set-up
            
            %get battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;

            %prevents second operation input from user
            app.CheckpointAButton.Enable = 'off';
            app.CheckpointBButton.Enable = 'off';

            %% Drone Movement

            %takeoff and move to desired location
            takeoff(app.drone);
            moveright(app.drone,'Distance',2,'Speed',1);
            
            %start timer
            tim = tic;
            %while loop to look for text for 10 seconds or until text found
            while isempty(txt) || toc(tim)<=10
            
                %takepicture
                I = snapshot(app.cam);
                %Identifies text using OCR
                txt = ocr(I);
            
            end
            
            %If function to compare located text
            %if true
            if contains(txt.Text,"CHECKPOINT A") || contains(txt.Text,"CHECKPOINTA")

                %determine length of area monitoring
                if contains(app.TimeDropDown.Value,'30 Seconds')

                    m = 30;

                elseif contains(app.TimeDropDown.Value,'1 Minute')


                    m = 60;

                end
            
                %display in alerts line
                app.AlertsEditField.Value = 'Drone at Checkpoint A';
                
                montim = tic;
                while (toc(montim) <= m)
                    
                    %takes image from camera
                    I = snapshot(app.cam);
                    %logs detected objects, their positions & confidence
                    %scores
                    [bboxes, scores, labels] = detect(detector, I)
                    %determines if vehicle or car is present
                    person = ismember("person",labels);
                    car = ismember("car",labels);
                    
                    %if present detected function
                    if person == 1 || car ==1
                        
                        %display in alerts line
                        app.AlertsEditField.Value = ('Presense at "CHECKPOINT A" detected');
                        break
                        
                    end
                    
                end
                
                %if present detected function
                if person == 1 || car ==1
                        
                    %insert annotation to show position of detection
                    I = insertObjectAnnotation(I,'rectangle',bboxes,labels);
                    %display annotated image
                    dI = figure('WindowState','maximized');
                    dI, imshow(I);
         
                    uialert(figurealert,"PRESENSE DETECTED! Press okay to return drone home","ALERT!","Modal",true);
                    uiwait(figurealert);
                        
               end
                        
                %move drone home
                moveleft(app.drone,'Distance',2,'Speed',1);
                land(app.drone);

                %get battery level and display
                app.BatteryLevelGauge.Value = app.drone.BatteryLevel;
            
            else
            
                %display in command line
                app.AlertsEditField.Value = 'Drone lost';
                land(app.drone);
            
            end

        end

        % Button pushed function: EmergancyLandButton
        function EmergancyLandButtonPushed(app, event)
            
            %Land drone
            land(app.drone);
            %display in alerts line
            app.AlertsEditField.Value = 'Emergancy Land';
            %clear drone to prevent operation
            clear(app.drone);

        end

        % Button pushed function: CheckpointBButton
        function CheckpointBButtonPushed(app, event)
            %% Monitor Set-up

            %get battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;

            %prevents second operation input from user
            app.CheckpointAButton.Enable = 'off';
            app.CheckpointBButton.Enable = 'off';

            %% Drone Movement
            
            %takeoff and move to desired location
            takeoff(app.drone);
            moveleft(app.drone,'Distance',2,'Speed',1);
            
            %start timer
            tim = tic;
            %while loop to look for text for 10 seconds or until text found
            while isempty(txt) || toc(tim) < 10
            
                %takepicture
                I = snapshot(app.cam);
                %Identifies text using OCR
                txt = ocr(I);
            
            end
            
            %If function to compare located text
            %if true
            if contains(txt.Text,"CHECKPOINT B") || contains(txt.Text,"CHECKPOINTB")

                %determine length of area monitoring
                if contains(app.TimeDropDown.Value,'30 Seconds')

                    m = 30;

                elseif contains(app.TimeDropDown.Value,'1 Minute')


                    m = 60;

                end
            
                %display in alerts line
                app.AlertsEditField.Value = 'Drone at Checkpoint B';
                
                montim = tic;
                while (toc(montim) <= m)
                    
                    %takes image from camera
                    I = snapshot(app.cam);
                    %logs detected objects, their positions & confidence
                    %scores
                    [bboxes, scores, labels] = detect(detector, I)
                    %determines if vehicle or car is present
                    person = ismember("person",labels)
                    car = ismember("car",labels);
                    
                    %if present detected function
                    if person == 1 || car ==1
                        
                        %display in alerts line
                        app.AlertsEditField.Value = ('Presense at "CHECKPOINT B" detected');
                        break
                        
                    end
                    
                end
                
                %if present detected function
                if person == 1 || car ==1
                        
                    %insert annotation to show position of detection
                    I = insertObjectAnnotation(I,'rectangle',bboxes,labels);
                    %display annotated image
                    dI = figure('WindowState','maximized');
                    dI, imshow(I);
         
                    uialert(figurealert,"PRESENSE DETECTED! Press okay to return drone home","ALERT!","Modal",true);
                    uiwait(figurealert);
                        
                end
                
                %move drone home
                moveright(app.drone,'Distance',2,'Speed',1);
                land(app.drone);

                %get battery level and display
                app.BatteryLevelGauge.Value = app.drone.BatteryLevel;
            
            else
            
                %display in command line
                app.AlertsEditField.Value = 'Drone lost';
                land(app.drone);
            
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {509, 509};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MonitorAreaPanel = uipanel(app.LeftPanel);
            app.MonitorAreaPanel.Title = 'Monitor Area';
            app.CheckpointAButton = uibutton(app.MonitorAreaPanel, 'push');
            app.CheckpointAButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpointAButtonPushed, true);
            app.CheckpointAButton.Text = 'Checkpoint A';
            app.CheckpointBButton = uibutton(app.MonitorAreaPanel, 'push');
            app.CheckpointBButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpointBButtonPushed, true);
            app.CheckpointBButton.Text = 'Checkpoint B';
            app.TimeDropDownLabel = uilabel(app.MonitorAreaPanel);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.MonitorAreaPanel);
            app.TimeDropDown.Items = {'30 Seconds', '1 Minute'};
            app.TimeDropDown.Value = '30 Seconds';
            app.DroneRespondingPanel = uipanel(app.LeftPanel);
            app.DroneRespondingPanel.Tooltip = {'When green, drone is responding to corresponding alarm'};
            app.DroneRespondingPanel.Title = 'Drone Responding';
            app.CheckpointALampLabel = uilabel(app.DroneRespondingPanel);
            app.CheckpointALampLabel.Text = 'Checkpoint A';
            app.CheckpointALamp = uilamp(app.DroneRespondingPanel);
            app.CheckpointBLampLabel = uilabel(app.DroneRespondingPanel);
            app.CheckpointBLampLabel.Text = 'Checkpoint B';
            app.CheckpointBLamp = uilamp(app.DroneRespondingPanel);
            app.EmergancyFunctionsPanel = uipanel(app.LeftPanel);
            app.EmergancyFunctionsPanel.Title = 'Emergancy Functions';
            app.ReturnHomeButton = uibutton(app.EmergancyFunctionsPanel, 'push');
            app.ReturnHomeButton.Text = 'Return Home';
            app.EmergancyLandButton = uibutton(app.EmergancyFunctionsPanel, 'push');
            app.EmergancyLandButton.ButtonPushedFcn = createCallbackFcn(app, @EmergancyLandButtonPushed, true);
            app.EmergancyLandButton.Text = 'Emergancy Land';
            app.BatteryLevelGaugeLabel = uilabel(app.EmergancyFunctionsPanel);
            app.BatteryLevelGaugeLabel.Text = 'Battery Level';
            app.BatteryLevelGauge = uigauge(app.EmergancyFunctionsPanel, 'linear');
            app.BatteryLevelGauge.MajorTicks = [0 20 40 60 80 100];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LiveFeedLabel = uilabel(app.RightPanel);
            app.LiveFeedLabel.Text = 'Live Feed';
            app.AlertsEditFieldLabel = uilabel(app.RightPanel);
            app.AlertsEditFieldLabel.Text = 'Alerts';
            app.AlertsEditField = uieditfield(app.RightPanel, 'text');
            app.AlertsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end