classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        EmergancyFunctionsPanel  matlab.ui.container.Panel
        BatteryLevelGauge        matlab.ui.control.LinearGauge
        BatteryLevelGaugeLabel   matlab.ui.control.Label
        EmergancyLandButton      matlab.ui.control.Button
        ReturnHomeButton         matlab.ui.control.Button
        DroneRespondingPanel     matlab.ui.container.Panel
        CheckpointBLamp          matlab.ui.control.Lamp
        CheckpointBLampLabel     matlab.ui.control.Label
        CheckpointALamp          matlab.ui.control.Lamp
        CheckpointALampLabel     matlab.ui.control.Label
        MonitorAreaPanel         matlab.ui.container.Panel
        TimeDropDown             matlab.ui.control.DropDown
        TimeDropDownLabel        matlab.ui.control.Label
        CheckpointBButton        matlab.ui.control.Button
        CheckpointAButton        matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        LiveFeedLabel            matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)

        drone;
        cam;

    end
    
    methods (Access = public)
        
        function alarm(app)
            
            %make "t" empty
            t = [];
            %defines serial port and baud rate
            s = serialport("COM5",115200);
            
            while (isempty(t))
                
                %red serial line
                t = readline(s);
            
            end

            if contains(t,"A")

                respondA(app);

            elseif contains(t,"B")

                respondB(app);

            else

                disp("Error!");

            end
              
              
        end

        function respondA(app)
           
            if app.drone.State == "landed"

                takeoff(app.drone);

            end

            disp("A");
            land(app.drone);


        end

        function respondB(app)

            if app.drone.State == "landed"

                takeoff(app.drone);

            end

            disp("B");
            land(app.drone);

        end

    end


    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %connect to drone and camera
            app.drone = ryze();
            app.cam = camera(app.drone);
            
            %set-up camera view frame
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes,'tight');
            %display feed into above frame
            preview(app.cam,im);
            %release axes view to show feed
            app.UIAxes.Visible = 'on';
            %get initial battery level and display
            app.BatteryLevelGauge.Value = app.drone.BatteryLevel;

            t = timer;
            t.Period = 5;
            t.TasksToExecute = inf;
            t.ExecutionMode = 'fixedRate';
            t.TimerFcn = @(~,~)alarm(app);
            
            start(t);


        end

        % Button pushed function: CheckpointAButton
        function CheckpointAButtonPushed(app, event)
         
            
                %specifying checkpoint text parameters
                A  = "CHECKPOINTA";
                A2 = "CHECKPOINT A";
                
                %Move Drone to correct position
                if app.drone.State == "landed"
    
                    takeoff(app.drone);
    
                end
                
                %start timer
                tic;
                %While text not detected for 30 seconds
                while (toc < 30)
            
                    %takepicture
                    I = snapshot(app.cam);
                    %Identifies text
                    txt = ocr(I);
            
                    if 	~isempty(txt)
            
                        %referencing detected text to pre-determined text
                        x  = contains(txt.Text,A);
                        x2 = contains(txt.Text,A2);
            
                        %if at checkpoint B
                        if x == 1 || x2 == 1
            
                            %dipslay in command line
                            disp("Checkpoint A");
                            %break from while loop
                            break
            
                        end
                    end
                end

                land(app.drone);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {509, 509};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MonitorAreaPanel = uipanel(app.LeftPanel);
            app.MonitorAreaPanel.Title = 'Monitor Area';
            app.CheckpointAButton = uibutton(app.MonitorAreaPanel, 'push');
            app.CheckpointAButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpointAButtonPushed, true);
            app.CheckpointAButton.Text = 'Checkpoint A';
            app.CheckpointBButton = uibutton(app.MonitorAreaPanel, 'push');
            app.CheckpointBButton.Text = 'Checkpoint B';
            app.TimeDropDownLabel = uilabel(app.MonitorAreaPanel);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.MonitorAreaPanel);
            app.TimeDropDown.Items = {'30 Seconds', '1 Minute'};
            app.TimeDropDown.Value = '30 Seconds';
            app.DroneRespondingPanel = uipanel(app.LeftPanel);
            app.DroneRespondingPanel.Title = 'Drone Responding';
            app.CheckpointALampLabel = uilabel(app.DroneRespondingPanel);
            app.CheckpointALampLabel.Text = 'Checkpoint A';
            app.CheckpointALamp = uilamp(app.DroneRespondingPanel);
            app.CheckpointBLampLabel = uilabel(app.DroneRespondingPanel);
            app.CheckpointBLampLabel.Text = 'Checkpoint B';
            app.CheckpointBLamp = uilamp(app.DroneRespondingPanel);
            app.EmergancyFunctionsPanel = uipanel(app.LeftPanel);
            app.EmergancyFunctionsPanel.Title = 'Emergancy Functions';
            app.ReturnHomeButton = uibutton(app.EmergancyFunctionsPanel, 'push');
            app.ReturnHomeButton.Text = 'Return Home';
            app.EmergancyLandButton = uibutton(app.EmergancyFunctionsPanel, 'push');
            app.EmergancyLandButton.Text = 'Emergancy Land';
            app.BatteryLevelGaugeLabel = uilabel(app.EmergancyFunctionsPanel);
            app.BatteryLevelGaugeLabel.Text = 'Battery Level';
            app.BatteryLevelGauge = uigauge(app.EmergancyFunctionsPanel, 'linear');
            app.BatteryLevelGauge.MajorTicks = [0 20 40 60 80 100];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LiveFeedLabel = uilabel(app.RightPanel);
            app.LiveFeedLabel.Text = 'Live Feed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end